classdef CM_AppDesigner_FSCV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSCV_Analysis_26thJune2024UIFigure  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        UpdateScale               matlab.ui.control.Button
        ColorScaleMax             matlab.ui.control.NumericEditField
        ColorScaleEditFieldLabel  matlab.ui.control.Label
        SelectFilter              matlab.ui.control.DropDown
        FilterLabel               matlab.ui.control.Label
        LampPlot4                 matlab.ui.control.Lamp
        ChLabel                   matlab.ui.control.Label
        LampPlot3                 matlab.ui.control.Lamp
        ChLabel_2                 matlab.ui.control.Label
        LampPlot2                 matlab.ui.control.Lamp
        ChLabel_3                 matlab.ui.control.Label
        LampPlot1                 matlab.ui.control.Lamp
        Label                     matlab.ui.control.Label
        ChDropDown4               matlab.ui.control.DropDown
        Label_2                   matlab.ui.control.Label
        ChDropDown3               matlab.ui.control.DropDown
        Label_3                   matlab.ui.control.Label
        ChDropDown2               matlab.ui.control.DropDown
        Label_4                   matlab.ui.control.Label
        ChDropDown1               matlab.ui.control.DropDown
        Label_5                   matlab.ui.control.Label
        DataTable                 matlab.ui.control.Table
        TitleText                 matlab.ui.control.EditField
        ColorBarAxes              matlab.ui.control.UIAxes
        EventsAxes                matlab.ui.control.UIAxes
        CvPlot                    matlab.ui.control.UIAxes
        DopaminePlot              matlab.ui.control.UIAxes
        ColorPlot4                matlab.ui.control.UIAxes
        ColorPlot3                matlab.ui.control.UIAxes
        ColorPlot2                matlab.ui.control.UIAxes
        ColorPlot1                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        numOfChannels;
        channelsToDisplay;
        indicesOfColorPlots;
        appDesColorPlots; 
        fscvColors;             
                                                                
        isPcrCalculated;
        isChan1CVPlotShown;

        ch16anodalLimitText;
        ch16cathodalLimitText;
        
        ch16anodalLimitVal;
        ch16cathodalLimitVal;
        
        ch16scanFrequency;

        ch16sampleFrequency;
        
        ch16fileLengthInSeconds;
        ch16eventData;
        
        displayEvents;
        samplesPerScan;

        colorPlotMaxScale;
        colorMap;
        colorScale;

        imageFilter;
    end
    
    methods (Access = private)

        function set16ChannelValues(app)

            app.ch16anodalLimitText = "+1.4";
            app.ch16cathodalLimitText = "-0.3";
            
            app.ch16anodalLimitVal = 1.4;
            app.ch16cathodalLimitVal = -0.3;

            app.samplesPerScan = 214;   % Other 214
            app.ch16scanFrequency = 10;

            app.ch16sampleFrequency = 25000;

            app.ch16fileLengthInSeconds = 60;
            app.ch16eventData = [];
            
            % **** set display events here ****
            
            % app.displayEvents = 1:1000;
            app.displayEvents = [128, 100, 101, 105, 22, 23, 24];  
        end


        function startScript(app)

            app.set16ChannelValues();

            % calculate the map and scale of the color plots
            % initial value = [-0.66, 1]
            app.colorPlotMaxScale = 1;
            app.ColorScaleMax.Value = app.colorPlotMaxScale; 
            app.calculateColorMapAndScale(); 

            % default image filter - Gaussian
            app.imageFilter = "Gaussian";
            app.setFilterSelector();

            app.isChan1CVPlotShown = false;

            app.isPcrCalculated = true;

            app.fscvColors = [
                1 0 0;             % red
                1 0.1 1;           % pink
                1 0.9 0;           % yellow
                0.7 1 0.2;         % light green
                0.2 0 0.8;         % dark blue
                0.3 .7 0;          % med green
                0.0 .5 1;          % light blue
                0.5 0 1;           % purple

                1 0 0;             % red
                1 0.1 1;           % pink
                1 0.9 0;           % yellow
                0.7 1 0.2;         % light green
                0.2 0 0.8;         % dark blue
                0.3 .7 0;          % med green
                0.0 .5 1;          % light blue
                0.5 0 1];          % purple

            % lamp start-up color
            app.LampPlot1.Color = [0, 0, 0]; 
            app.LampPlot2.Color = [0, 0, 0];
            app.LampPlot3.Color = [0, 0, 0];
            app.LampPlot4.Color = [0, 0, 0]; 

            % make app invisible until uigetfile is displayed
            app.FSCV_Analysis_26thJune2024UIFigure.Visible = 'off'; 

            % get the file here
            [selectedFileName,selectedPathName] = ... 
                        uigetfile('*.*', 'Select file');

            app.FSCV_Analysis_26thJune2024UIFigure.Visible = 'on'; 
            
            % create color plot array
            app.appDesColorPlots = [app.ColorPlot1, app.ColorPlot2, ... 
                                    app.ColorPlot3, app.ColorPlot4];
                        
            app.channelsToDisplay = 1:4;
            app.indicesOfColorPlots = 1:4;
            app.setInputChannelsTo4();

            CM_plotEphysFSCVmulti(app, app.FSCV_Analysis_26thJune2024UIFigure, ...
                            selectedFileName, selectedPathName); 
       
            
            % update the lamps to reflect the initial channels
            app.updateLampColors();
        end
    end
    
    methods (Access = public)
        

        function setFilterSelector(app)
            app.SelectFilter.Items = ["Gaussian", "Low Pass", "No Filter"];
            app.SelectFilter.Value = app.imageFilter;
        end

        function setInputChannelsTo4(app)

            channelNumsString = [ "X", string(1:4) ];
            app.ChDropDown1.Items = channelNumsString;
            app.ChDropDown2.Items = channelNumsString;
            app.ChDropDown3.Items = channelNumsString;
            app.ChDropDown4.Items = channelNumsString;

            app.ChDropDown1.Value = '1';
            app.ChDropDown2.Value = '2';
            app.ChDropDown3.Value = '3';
            app.ChDropDown4.Value = '4';

            app.numOfChannels = 4;
        end

        function setAnalogInputsTo16(app)
            
            channelNumsString = [ "X", string(1:16) ];
            app.ChDropDown1.Items = channelNumsString;
            app.ChDropDown2.Items = channelNumsString;
            app.ChDropDown3.Items = channelNumsString;
            app.ChDropDown4.Items = channelNumsString;

            app.ChDropDown1.Value = '1';
            app.ChDropDown2.Value = '2';
            app.ChDropDown3.Value = '3';
            app.ChDropDown4.Value = '4';

            app.numOfChannels = 16;
        end
        
        function updateChannelsSelected(app)
        
            dropCh1 = string( app.ChDropDown1.Value );    
            dropCh2 = string( app.ChDropDown2.Value );    
            dropCh3 = string( app.ChDropDown3.Value );    
            dropCh4 = string( app.ChDropDown4.Value );    

            selectedChannels = [dropCh1; dropCh2; dropCh3; dropCh4];

            displayChannels = [];
            displayIndices = [];

            for i = 1 : 4
                channelValue = selectedChannels(i);

                if ~strcmp(channelValue, "X")
                    displayChannels = [displayChannels, str2num(channelValue)]; %#ok<ST2NM,AGROW> 
                    displayIndices = [displayIndices, i]; %#ok<AGROW> 
                end
            end    

            app.channelsToDisplay = displayChannels;
            app.indicesOfColorPlots = displayIndices;

            app.updateLampColors();
            disp("Update Channel Selected Called");
            disp(app.channelsToDisplay);
            disp(app.indicesOfColorPlots);
        end

        function obj = getColorPlots(app)
            obj = app.appDesColorPlots;
        end

        function obj = getLastColorPlotIndex(app)
            if(isempty(app.indicesOfColorPlots))
                obj = [];
            else    
                obj = app.indicesOfColorPlots(end);
            end    
        end    

        function obj = getDopaminePlot(app)
            obj = app.DopaminePlot;
        end

        function obj = getCvPlot(app)
            obj = app.CvPlot;
        end

        function obj = getDataTable(app) 
            obj = app.DataTable;
        end    

        function obj = getTitleText(app)
            obj = app.TitleText;
        end   

        function obj = getChannelsToDisplay(app)
            obj = app.channelsToDisplay;
        end

        function obj = getIndicesOfColorPlots(app) 
            obj = app.indicesOfColorPlots;
        end    

        function obj = getfscvColors(app)
            obj = app.fscvColors; 
        end    

        function updateLampColors(app)

            selectedChannels = [app.ChDropDown1, app.ChDropDown2, ... 
                                app.ChDropDown3, app.ChDropDown4];
                       
            lamps = [app.LampPlot1, app.LampPlot2, ... 
                     app.LampPlot3, app.LampPlot4];
                            
            for i = 1:4
                channelValue = selectedChannels(i).Value;
                lamp = lamps(i);

                % grey out 
                if strcmp(channelValue, "X")
                    lamp.Color = [0, 0, 0];

                % set color    
                else    
                    channelValueNum = str2num(channelValue); %#ok<ST2NM> 
                    lamp.Color = app.fscvColors(channelValueNum, :);
                end    
            end    
        end  

        function value = getNumOfChannels(app)
            value = app.numOfChannels;
        end 

        function val = getAnodalLimitText(app)
            val = app.ch16anodalLimitText;
        end

        function val = getCathodalLimitText(app)
            val = app.ch16cathodalLimitText;
        end    

        function val = getAnodalLimitVal(app)
            val = app.ch16anodalLimitVal;
        end

        function val = getCathodalLimitVal(app)
            val = app.ch16cathodalLimitVal;
        end  

        function val = getIsPcrCalculated(app)
            val = app.isPcrCalculated;
        end    

        function val = getScanFrequency16Ch(app)
            val = app.ch16scanFrequency; 
        end

        function val = getSamplesPerScan(app)
            val = app.samplesPerScan;
        end    
        
        function val = getSampleFrequency(app)
            val = app.ch16sampleFrequency;
        end    

        function setAnodalLimit(app, anodalLimit)
            app.ch16anodalLimitVal = anodalLimit;
        end

        function setCathodalLimit(app, cathodalLimit)
            app.ch16cathodalLimitVal = cathodalLimit;
        end

        function setSamplesPerScan(app, samplesPerScan)
            app.samplesPerScan = samplesPerScan;
        end

        function setScanFrequency(app, scanFrequency)
            app.ch16scanFrequency = scanFrequency;
        end    

        function setSampleFrequency(app, sampleFrequency)
            app.ch16sampleFrequency = sampleFrequency;
        end 

        function show16ChanParameters(app)
            disp(['Anodal Limit: ', string(app.ch16anodalLimitVal)]);
            disp(['Cathodal Limit: ', string(app.ch16cathodalLimitVal)]);
            disp(['Samples Per Scan: ', string(app.samplesPerScan)]);
            disp(['Scan Frequency: ', string(app.ch16scanFrequency)]);
            disp(['Sample Frequency: ', string(app.ch16sampleFrequency)]);
 
            disp(['File Length (sec): ', ...
                string(app.ch16fileLengthInSeconds)]);       
        end    

        function val = isCVTestPlotShown(app)
            val = app.isChan1CVPlotShown;
        end

        function setFileLengthInSecs(app, fileLengthInSeconds)
            app.ch16fileLengthInSeconds = fileLengthInSeconds;
        end

        function val = getFileLengthInSecs(app)
            val = app.ch16fileLengthInSeconds;
        end

        function setEventData(app, eventData)
            app.ch16eventData = eventData;
        end    

        function val = getEventData(app)
            val = app.ch16eventData;
        end    

        function val = getDisplayEvents(app) 
            val = app.displayEvents;
        end    

        function item = getColorBarAxes(app)
            item = app.ColorBarAxes;
        end    

        function item = getImageFilter(app)
            item = app.imageFilter;
        end    

        function calculateColorMapAndScale(app) 

            [app.colorMap, colorMinScale] = setColorScale(app.colorPlotMaxScale);        
            app.colorScale = [colorMinScale, app.colorPlotMaxScale];
        end

        function item = getColorMap(app)
            item = app.colorMap;
        end

        function item = getColorScale(app)
            item = app.colorScale;
        end    

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % call the start script
            startScript(app);
        end

        % Window key press function: FSCV_Analysis_26thJune2024UIFigure
        function FSCV_Analysis_26thJune2024UIFigureWindowKeyPress(app, event)
            
            keyPressed = event.Key;            
            CM_buttonpress(app, keyPressed);
            
        end

        % Window button down function: FSCV_Analysis_26thJune2024UIFigure
        function FSCV_Analysis_26thJune2024UIFigureWindowButtonDown(app, event)
           
            % figure -> axes -> image
            figureObj = event.Source;
            childObj = event.Source.CurrentObject;
            
            % Values - 'normal' (left), 'alt' (right), 'extend' (center)
            typeOfClick = figureObj.SelectionType;

            % [x, y] pixels
            clickPosOnFigure = figureObj.CurrentPoint;

            % if there is a child object and it is an image
            if ~isempty(childObj) && strcmp(childObj.Type, 'image')
           
                % get the plot position from the parent (axis object)
                plotPosOnFigure = childObj.Parent.InnerPosition;

                % [x, y] - [left bottom width height]       
                clickPosOnPlot = ...
                 [clickPosOnFigure(1) - plotPosOnFigure(1),...
                  clickPosOnFigure(2) - plotPosOnFigure(2)];
                
                % call mouse handler function
                CM_mouseclick(app, typeOfClick, plotPosOnFigure, ...
                              clickPosOnPlot);

            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
           
            CM_menuselect(app);
            
        end

        % Value changed function: ChDropDown1
        function ChDropDown1ValueChanged(app, event)
            
            app.updateChannelsSelected();
            CM_updatePlotsNewChannels(app);

        end

        % Value changed function: ChDropDown2
        function ChDropDown2ValueChanged(app, event)
            
            app.updateChannelsSelected();
            CM_updatePlotsNewChannels(app);

        end

        % Value changed function: ChDropDown3
        function ChDropDown3ValueChanged(app, event)
            
            app.updateChannelsSelected();
            CM_updatePlotsNewChannels(app);

        end

        % Value changed function: ChDropDown4
        function ChDropDown4ValueChanged(app, event)
            
            app.updateChannelsSelected();
            CM_updatePlotsNewChannels(app);

        end

        % Value changed function: SelectFilter
        function SelectFilterValueChanged(app, event)
            
            % get selected filter
            app.imageFilter = app.SelectFilter.Value;
            
            % call function to update plots with new image filter
            CM_updatePlotsNewParameters(app);

        end

        % Button pushed function: UpdateScale
        function UpdateScaleButtonPushed(app, event)
            
            % update max scale value
            app.colorPlotMaxScale = app.ColorScaleMax.Value;
            
            % update map and scale
            app.calculateColorMapAndScale(); 

            % update color plots
            CM_updatePlotsNewParameters(app);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSCV_Analysis_26thJune2024UIFigure = uifigure('Visible', 'off');
            app.FSCV_Analysis_26thJune2024UIFigure.Name = 'FSCV_Analysis_26thJune2024';
            app.FSCV_Analysis_26thJune2024UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @FSCV_Analysis_26thJune2024UIFigureWindowButtonDown, true);
            app.FSCV_Analysis_26thJune2024UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @FSCV_Analysis_26thJune2024UIFigureWindowKeyPress, true);
            app.Menu = uimenu(app.FSCV_Analysis_26thJune2024UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ColorPlot1 = uiaxes(app.FSCV_Analysis_26thJune2024UIFigure);
            ylabel(app.ColorPlot1, 'Voltage (V)')
            zlabel(app.ColorPlot1, 'Z')
            app.ColorPlot1.XLimitMethod = 'tight';
            app.ColorPlot1.YLimitMethod = 'tight';
            app.ColorPlot1.XTick = [];
            app.ColorPlot1.XTickLabel = '';
            app.ColorPlot1.YTick = [];
            app.ColorPlot1.Tag = 'ColorPlot1';
            app.ColorPlot2 = uiaxes(app.FSCV_Analysis_26thJune2024UIFigure);
            ylabel(app.ColorPlot2, 'Voltage (V)')
            zlabel(app.ColorPlot2, 'Z')
            app.ColorPlot2.XLimitMethod = 'tight';
            app.ColorPlot2.YLimitMethod = 'tight';
            app.ColorPlot2.XTick = [];
            app.ColorPlot2.XTickLabel = '';
            app.ColorPlot2.YTick = [];
            app.ColorPlot2.Tag = 'ColorPlot2';
            app.ColorPlot3 = uiaxes(app.FSCV_Analysis_26thJune2024UIFigure);
            ylabel(app.ColorPlot3, 'Voltage (V)')
            zlabel(app.ColorPlot3, 'Z')
            app.ColorPlot3.XLimitMethod = 'tight';
            app.ColorPlot3.YLimitMethod = 'tight';
            app.ColorPlot3.XTick = [];
            app.ColorPlot3.XTickLabel = '';
            app.ColorPlot3.YTick = [];
            app.ColorPlot3.Tag = 'ColorPlot3';
            app.ColorPlot4 = uiaxes(app.FSCV_Analysis_26thJune2024UIFigure);
            ylabel(app.ColorPlot4, 'Voltage (V)')
            zlabel(app.ColorPlot4, 'Z')
            app.ColorPlot4.XLimitMethod = 'tight';
            app.ColorPlot4.YLimitMethod = 'tight';
            app.ColorPlot4.XTick = [];
            app.ColorPlot4.XTickLabel = '';
            app.ColorPlot4.YTick = [];
            app.ColorPlot4.Tag = 'ColorPlot4';
            app.DopaminePlot = uiaxes(app.FSCV_Analysis_26thJune2024UIFigure);
            ylabel(app.DopaminePlot, 'DM')
            zlabel(app.DopaminePlot, 'Z')
            app.DopaminePlot.XTick = [];
            app.DopaminePlot.XTickLabel = '';
            app.CvPlot = uiaxes(app.FSCV_Analysis_26thJune2024UIFigure);
            title(app.CvPlot, 'CV at selected timepoint')
            xlabel(app.CvPlot, 'E_a (V)')
            ylabel(app.CvPlot, 'I (nA)')
            zlabel(app.CvPlot, 'Z')
            app.CvPlot.XLim = [-0.4 1.3];
            app.CvPlot.XTick = [-0.4 -0.2 0 0.2 0.4 0.6 0.8 1 1.3];
            app.CvPlot.XTickLabel = {'-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.3'};
            app.EventsAxes = uiaxes(app.FSCV_Analysis_26thJune2024UIFigure);
            ylabel(app.EventsAxes, 'Events')
            zlabel(app.EventsAxes, 'Z')
            app.EventsAxes.XLim = [0 60];
            app.EventsAxes.YLim = [0 2];
            app.EventsAxes.XTick = [];
            app.EventsAxes.YTick = [0 1 2];
            app.EventsAxes.YTickLabel = {'0'; '1'; '2'};
            app.ColorBarAxes = uiaxes(app.FSCV_Analysis_26thJune2024UIFigure);
            zlabel(app.ColorBarAxes, 'Z')
            app.ColorBarAxes.XTick = [];
            app.ColorBarAxes.YTick = [];
            app.TitleText = uieditfield(app.FSCV_Analysis_26thJune2024UIFigure, 'text');
            app.DataTable = uitable(app.FSCV_Analysis_26thJune2024UIFigure);
            app.DataTable.ColumnName = '';
            app.DataTable.RowName = {};
            app.Label_5 = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.Label_5.Text = '';
            app.ChDropDown1 = uidropdown(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChDropDown1.Items = {};
            app.ChDropDown1.ValueChangedFcn = createCallbackFcn(app, @ChDropDown1ValueChanged, true);
            app.ChDropDown1.Value = {};
            app.Label_4 = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.Label_4.Text = '';
            app.ChDropDown2 = uidropdown(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChDropDown2.Items = {};
            app.ChDropDown2.ValueChangedFcn = createCallbackFcn(app, @ChDropDown2ValueChanged, true);
            app.ChDropDown2.Value = {};
            app.Label_3 = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.Label_3.Text = '';
            app.ChDropDown3 = uidropdown(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChDropDown3.Items = {};
            app.ChDropDown3.ValueChangedFcn = createCallbackFcn(app, @ChDropDown3ValueChanged, true);
            app.ChDropDown3.Value = {};
            app.Label_2 = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.Label_2.Text = '';
            app.ChDropDown4 = uidropdown(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChDropDown4.Items = {};
            app.ChDropDown4.ValueChangedFcn = createCallbackFcn(app, @ChDropDown4ValueChanged, true);
            app.ChDropDown4.Value = {};
            app.Label = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.Label.Text = 'Ch';
            app.LampPlot1 = uilamp(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChLabel_3 = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChLabel_3.Text = 'Ch';
            app.LampPlot2 = uilamp(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChLabel_2 = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChLabel_2.Text = 'Ch';
            app.LampPlot3 = uilamp(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChLabel = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ChLabel.Text = 'Ch';
            app.LampPlot4 = uilamp(app.FSCV_Analysis_26thJune2024UIFigure);
            app.FilterLabel = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.FilterLabel.Text = 'Filter';
            app.SelectFilter = uidropdown(app.FSCV_Analysis_26thJune2024UIFigure);
            app.SelectFilter.ValueChangedFcn = createCallbackFcn(app, @SelectFilterValueChanged, true);
            app.ColorScaleEditFieldLabel = uilabel(app.FSCV_Analysis_26thJune2024UIFigure);
            app.ColorScaleEditFieldLabel.Text = 'Color Scale';
            app.ColorScaleMax = uieditfield(app.FSCV_Analysis_26thJune2024UIFigure, 'numeric');
            app.UpdateScale = uibutton(app.FSCV_Analysis_26thJune2024UIFigure, 'push');
            app.UpdateScale.ButtonPushedFcn = createCallbackFcn(app, @UpdateScaleButtonPushed, true);
            app.UpdateScale.Text = 'Update';
            % Show the figure after all components are created
            app.FSCV_Analysis_26thJune2024UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CM_AppDesigner_FSCV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSCV_Analysis_26thJune2024UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSCV_Analysis_26thJune2024UIFigure)
        end
    end
end